classdef MATLAB_Car_MQTT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab6UIFigure      matlab.ui.Figure
        Label             matlab.ui.control.Label
        SpeedSlider       matlab.ui.control.Slider
        SpeedSliderLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        priorKey = "";
    end
    
    methods (Access = private)

        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message)
            messageStruct = jsondecode(message);
            messageType = messageStruct.type

            
            if strcmp(messageType, "set_speeds")
                messagePayload = messageStruct.payload
            end

        end


        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;

            if nargin > 2
                messageStruct.payload = messagePayload;
            end

            messageJson = jsonencode(messageStruct);
            write(app.mqClient, "me435/mccreebs/to_pi", messageJson);
            fprintf("Sent: %s\n", messageJson);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/mccreebs/#", "Callback", @app.myCallback)
        end

        % Key press function: Lab6UIFigure
        function Lab6UIFigureKeyPress(app, event)
            key = event.Key
            
            if strcmp(key, app.priorKey)
                return; % The key is being held down. Ignore This
            end
            app.priorKey = key;
            
            speed = round(app.SpeedSlider.Value);

            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds", [speed, speed, speed, speed])
            end

            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds", [-speed, -speed, -speed, -speed])
            end

            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds", [-speed, speed, speed, -speed])
            end

            if strcmp(key, "rightarrow")
                app.sendMessage("set_speeds", [speed, -speed, -speed, speed])
            end

            if strcmp(key, "a")
                app.sendMessage("set_speeds", [-speed/2, -speed/2, speed/2, speed/2])
            end

            if strcmp(key, "d")
                app.sendMessage("set_speeds", [speed/2, speed/2, -speed/2, -speed/2])
            end

            if strcmp(key, "q")
                app.sendMessage("beep")
            end
        end

        % Key release function: Lab6UIFigure
        function Lab6UIFigureKeyRelease(app, event)
            app.priorKey = "";
            app.sendMessage("stop")

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab6UIFigure = uifigure('Visible', 'off');
            app.Lab6UIFigure.Name = 'Lab 6';
            app.Lab6UIFigure.KeyPressFcn = createCallbackFcn(app, @Lab6UIFigureKeyPress, true);
            app.Lab6UIFigure.KeyReleaseFcn = createCallbackFcn(app, @Lab6UIFigureKeyRelease, true);
            app.SpeedSliderLabel = uilabel(app.Lab6UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.Lab6UIFigure);
            app.SpeedSlider.Value = 40;
            app.Label = uilabel(app.Lab6UIFigure);
            app.Label.Text = 'Click in the area below to use the keyboard';
            % Show the figure after all components are created
            app.Lab6UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_Car_MQTT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab6UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab6UIFigure)
        end
    end
end